classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        UIAxes_RG            matlab.ui.control.UIAxes
        UIAxes_GG            matlab.ui.control.UIAxes
        UIAxes_BG            matlab.ui.control.UIAxes
        UIAxes_RC            matlab.ui.control.UIAxes
        UIAxes_GC            matlab.ui.control.UIAxes
        UIAxes_BC            matlab.ui.control.UIAxes
        UIAxes_RH            matlab.ui.control.UIAxes
        Slider_R             matlab.ui.control.Slider
        Slider_G             matlab.ui.control.Slider
        Slider_B             matlab.ui.control.Slider
        UIAxes_GH            matlab.ui.control.UIAxes
        UIAxes_BH            matlab.ui.control.UIAxes
        RedChannelLabel      matlab.ui.control.Label
        GreenChannelLabel    matlab.ui.control.Label
        BlueChannelLabel     matlab.ui.control.Label
        CloudsRGBLabel       matlab.ui.control.Label
        GroundRGBLabel       matlab.ui.control.Label
        GroundLabel_2        matlab.ui.control.Label
        CloudsLabel_2        matlab.ui.control.Label
        ThresholdRedLabel    matlab.ui.control.Label
        ThresholdGreenLabel  matlab.ui.control.Label
        ThresholdBlueLabel   matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global rbackvalue
            global gbackvalue
            global bbackvalue
            rbackvalue = 1;
            gbackvalue = 1;
            bbackvalue = 1;
            [redfil,redpat] = uigetfile('*.jpg','Choose a JPEG file for the red channel');
            [grefil,grepat] = uigetfile('*.jpg','Choose a JPEG file for the green channel');
            [blufil,blupat] = uigetfile('*.jpg','Choose a JPEG file for the blue channel');
            redname = fullfile(redpat,redfil);
            redchan = imread(redname);
            grename = fullfile(grepat,grefil);
            grechan = imread(grename);
            bluname = fullfile(blupat,blufil);
            bluchan = imread(bluname);
            
            hold(app.UIAxes_RH)
            histogram(redchan,'Parent',app.UIAxes_RH)
            %set(app.UIAxes_RH, 'XTickMode', 'manual', 'YTickMode', 'manual')
            hold(app.UIAxes_GH)
            histogram(grechan,'Parent',app.UIAxes_GH)
            %set(app.UIAxes_GH, 'XTickMode', 'manual', 'YTickMode', 'manual')
            hold(app.UIAxes_BH)
            histogram(bluchan,'Parent',app.UIAxes_BH)
            %set(app.UIAxes_BH, 'XTickMode', 'manual', 'YTickMode', 'manual')
        end

        % Value changed function: Slider_R
        function Slider_RValueChanged(app, event)
            global rbackvalue
            
            delete(xline(rbackvalue,'Parent',app.UIAxes_RH))
            value = app.Slider_R.Value;
            rbackvalue = value;
            xline(value,'r-','Parent',app.UIAxes_RH)
        end

        % Value changed function: Slider_G
        function Slider_GValueChanged(app, event)
            global gbackvalue
            
            delete(xline(gbackvalue,'Parent',app.UIAxes_GH))
            value = app.Slider_G.Value;
            gbackvalue = value;
            xline(value,'g-','Parent',app.UIAxes_GH)
        end

        % Value changed function: Slider_B
        function Slider_BValueChanged(app, event)
            global bbackvalue
            
            delete(xline(bbackvalue,'Parent',app.UIAxes_BH))
            value = app.Slider_B.Value;
            bbackvalue = value;
            xline(value,'b-','Parent',app.UIAxes_BH)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes_RG = uiaxes(app.UIFigure);
            title(app.UIAxes_RG, '')
            xlabel(app.UIAxes_RG, '')
            ylabel(app.UIAxes_RG, '')
            app.UIAxes_RG.XTick = [];
            app.UIAxes_RG.YTick = [];
            app.UIAxes_GG = uiaxes(app.UIFigure);
            title(app.UIAxes_GG, '')
            xlabel(app.UIAxes_GG, '')
            ylabel(app.UIAxes_GG, '')
            app.UIAxes_GG.XTick = [];
            app.UIAxes_GG.YTick = [];
            app.UIAxes_BG = uiaxes(app.UIFigure);
            title(app.UIAxes_BG, '')
            xlabel(app.UIAxes_BG, '')
            ylabel(app.UIAxes_BG, '')
            app.UIAxes_BG.XTick = [];
            app.UIAxes_BG.YTick = [];
            app.UIAxes_RC = uiaxes(app.UIFigure);
            title(app.UIAxes_RC, '')
            xlabel(app.UIAxes_RC, '')
            ylabel(app.UIAxes_RC, '')
            app.UIAxes_RC.XTick = [];
            app.UIAxes_RC.YTick = [];
            app.UIAxes_GC = uiaxes(app.UIFigure);
            title(app.UIAxes_GC, '')
            xlabel(app.UIAxes_GC, '')
            ylabel(app.UIAxes_GC, '')
            app.UIAxes_GC.XTick = [];
            app.UIAxes_GC.YTick = [];
            app.UIAxes_BC = uiaxes(app.UIFigure);
            title(app.UIAxes_BC, '')
            xlabel(app.UIAxes_BC, '')
            ylabel(app.UIAxes_BC, '')
            app.UIAxes_BC.XTick = [];
            app.UIAxes_BC.YTick = [];
            app.UIAxes_RH = uiaxes(app.UIFigure);
            title(app.UIAxes_RH, 'Histogram')
            xlabel(app.UIAxes_RH, 'Brightness')
            ylabel(app.UIAxes_RH, 'Number of Pixels')
            app.Slider_R = uislider(app.UIFigure);
            app.Slider_R.Limits = [0 256];
            app.Slider_R.ValueChangedFcn = createCallbackFcn(app, @Slider_RValueChanged, true);
            app.Slider_G = uislider(app.UIFigure);
            app.Slider_G.Limits = [0 256];
            app.Slider_G.ValueChangedFcn = createCallbackFcn(app, @Slider_GValueChanged, true);
            app.Slider_B = uislider(app.UIFigure);
            app.Slider_B.Limits = [0 256];
            app.Slider_B.ValueChangedFcn = createCallbackFcn(app, @Slider_BValueChanged, true);
            app.UIAxes_GH = uiaxes(app.UIFigure);
            title(app.UIAxes_GH, 'Histogram')
            xlabel(app.UIAxes_GH, 'Brightness')
            ylabel(app.UIAxes_GH, 'Number of Pixels')
            app.UIAxes_BH = uiaxes(app.UIFigure);
            title(app.UIAxes_BH, 'Histogram')
            xlabel(app.UIAxes_BH, 'Brightness')
            ylabel(app.UIAxes_BH, 'Number of Pixels')
            app.RedChannelLabel = uilabel(app.UIFigure);
            app.RedChannelLabel.Text = 'Red Channel';
            app.GreenChannelLabel = uilabel(app.UIFigure);
            app.GreenChannelLabel.Text = 'Green Channel';
            app.BlueChannelLabel = uilabel(app.UIFigure);
            app.BlueChannelLabel.Text = 'Blue Channel';
            app.CloudsRGBLabel = uilabel(app.UIFigure);
            app.CloudsRGBLabel.Text = 'Clouds (RGB)';
            app.GroundRGBLabel = uilabel(app.UIFigure);
            app.GroundRGBLabel.Text = 'Ground (RGB)';
            app.GroundLabel_2 = uilabel(app.UIFigure);
            app.GroundLabel_2.Text = 'Ground';
            app.CloudsLabel_2 = uilabel(app.UIFigure);
            app.CloudsLabel_2.Text = 'Clouds';
            app.ThresholdRedLabel = uilabel(app.UIFigure);
            app.ThresholdRedLabel.Text = 'Threshold (Red)';
            app.ThresholdGreenLabel = uilabel(app.UIFigure);
            app.ThresholdGreenLabel.Text = 'Threshold (Green)';
            app.ThresholdBlueLabel = uilabel(app.UIFigure);
            app.ThresholdBlueLabel.Text = 'Threshold (Blue)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end